classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PolinomiocubicoconvelocitassegnateTab  matlab.ui.container.Tab
        VelocitaTable                 matlab.ui.control.Table
        PosizioniTable                matlab.ui.control.Table
        GestionePanel                 matlab.ui.container.Panel
        AggiungiColButton             matlab.ui.control.Button
        durataintervalloSpinnerLabel  matlab.ui.control.Label
        durataintervalloSpinner       matlab.ui.control.Spinner
        EliminaColButton              matlab.ui.control.Button
        SimulaButton                  matlab.ui.control.Button
        PolinomiocubicoconvelocitcalcolateTab  matlab.ui.container.Tab
        ProfilitrapezoidaliconanticipoTab  matlab.ui.container.Tab
        SimulazionePanel              matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        GiuntiPanel                   matlab.ui.container.Panel
        GiuntiListBox                 matlab.ui.control.ListBox
        EliminaButton                 matlab.ui.control.Button
        TipologiaDropDownLabel        matlab.ui.control.Label
        TipologiaDropDown             matlab.ui.control.DropDown
        AggiungiButton                matlab.ui.control.Button
        RiferimentoDropDownLabel      matlab.ui.control.Label
        RiferimentoDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        tempi = [0]
        riferimenti = {}
        tipologie = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AggiungiButton
        function AggiungiButtonPushed(app, event)
            tipo = app.TipologiaDropDown.Value;
            app.GiuntiListBox.Items{end + 1} = tipo;
            app.tipologie{end + 1} = tipo
            ref = app.RiferimentoDropDown.Value
            app.riferimenti{end + 1} = ref
            
            [num_rows, num_cols] = size(app.PosizioniTable.Data);
            if num_rows == 0 && num_cols == 0
                app.PosizioniTable.Data = 0;
                app.VelocitaTable.Data = 0;
                return;
            end
            row = zeros(1, num_cols);
            app.PosizioniTable.Data = [app.PosizioniTable.Data; row];
            app.VelocitaTable.Data = [app.VelocitaTable.Data; row];
        end

        % Button pushed function: EliminaButton
        function EliminaButtonPushed(app, event)
            if isempty(app.GiuntiListBox.Value)
                uiwait(msgbox('Seleziona un giunto dalla lista prima di eliminare.', 'Errore', 'error', 'modal'));
                return;
            end
            [~,idx] = ismember(app.GiuntiListBox.Value, app.GiuntiListBox.Items);
            app.riferimenti(idx) = []
            app.tipologie(idx) = []
            size(app.tipologie)
            app.GiuntiListBox.Items(idx) = [];
            app.PosizioniTable.Data(idx, :) = [];
            app.VelocitaTable.Data(idx, :) = [];
        end

        % Button pushed function: AggiungiColButton
        function AggiungiColButtonPushed(app, event)
            [num_rows, num_cols] = size(app.PosizioniTable.Data);
            if num_rows == 0 && num_cols == 0
                uiwait(msgbox('Aggiungi un giunto prima di procedere.', 'Errore', 'error', 'modal'));
                return;
            end
            app.tempi(end + 1) = sum(app.tempi) + app.durataintervalloSpinner.Value;
            formatoPos = 'q(t%d)';
            formatoVel = 'v(t%d)';
            colonnaPos = sprintf(formatoPos, num_cols + 1);
            colonnaVel = sprintf(formatoVel, num_cols + 1);
            app.PosizioniTable.ColumnName{end + 1} = colonnaPos;
            app.VelocitaTable.ColumnName{end + 1} = colonnaVel;
            column = zeros(num_rows, 1);
            app.PosizioniTable.Data = [app.PosizioniTable.Data, column];
            app.VelocitaTable.Data = [app.VelocitaTable.Data, column];
        end

        % Button pushed function: EliminaColButton
        function EliminaColButtonPushed(app, event)
            [~, num_cols] = size(app.PosizioniTable.Data);
            if num_cols <= 1
                uiwait(msgbox('Impossibile eliminare il primo intervallo.', 'Errore', 'error', 'modal'));
                return;
            end
            app.PosizioniTable.Data = app.PosizioniTable.Data(:, 1 : end - 1);
            app.VelocitaTable.Data = app.VelocitaTable.Data(:, 1 : end - 1);
            app.PosizioniTable.ColumnName = app.PosizioniTable.ColumnName(1 : end - 1);
            app.VelocitaTable.ColumnName = app.VelocitaTable.ColumnName(1 : end - 1);
            app.tempi = app.tempi(1 : end - 1);
        end

        % Button pushed function: SimulaButton
        function SimulaButtonPushed(app, event)
            percorso = app.PosizioniTable.Data(:, :)
            velocita = app.VelocitaTable.Data(:, :)
            parametri = generaPolinomiCubici(percorso, velocita, app.tempi)
            %disp(parametri);
            stampaGrafico(parametri, app.tempi);
            animazioneGUI(parametri, app.tempi, app.tipologie, app.riferimenti, app.UIAxes, false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PolinomiocubicoconvelocitassegnateTab = uitab(app.TabGroup);
            app.PolinomiocubicoconvelocitassegnateTab.Title = 'Polinomio cubico con velocità assegnate';
            app.VelocitaTable = uitable(app.PolinomiocubicoconvelocitassegnateTab);
            app.VelocitaTable.ColumnName = {'v(t1)'};
            app.VelocitaTable.ColumnEditable = true;
            app.PosizioniTable = uitable(app.PolinomiocubicoconvelocitassegnateTab);
            app.PosizioniTable.ColumnName = {'q(t1)'};
            app.PosizioniTable.ColumnEditable = true;
            app.GestionePanel = uipanel(app.PolinomiocubicoconvelocitassegnateTab);
            app.GestionePanel.Title = 'Gestione';
            app.AggiungiColButton = uibutton(app.GestionePanel, 'push');
            app.AggiungiColButton.ButtonPushedFcn = createCallbackFcn(app, @AggiungiColButtonPushed, true);
            app.AggiungiColButton.Tooltip = {'Aggiunge intervallo di tempo'};
            app.AggiungiColButton.Text = 'Aggiungi';
            app.durataintervalloSpinnerLabel = uilabel(app.GestionePanel);
            app.durataintervalloSpinnerLabel.Text = 'durata intervallo:';
            app.durataintervalloSpinner = uispinner(app.GestionePanel);
            app.durataintervalloSpinner.Limits = [1 60];
            app.durataintervalloSpinner.Value = 1;
            app.EliminaColButton = uibutton(app.GestionePanel, 'push');
            app.EliminaColButton.ButtonPushedFcn = createCallbackFcn(app, @EliminaColButtonPushed, true);
            app.EliminaColButton.Tooltip = {'Elimina l''ultimo intervallo di tempo'};
            app.EliminaColButton.Text = 'Elimina';
            app.SimulaButton = uibutton(app.PolinomiocubicoconvelocitassegnateTab, 'push');
            app.SimulaButton.ButtonPushedFcn = createCallbackFcn(app, @SimulaButtonPushed, true);
            app.SimulaButton.Tooltip = {'Simula la traiettoria in base ia dati inseriti'};
            app.SimulaButton.Text = 'Simula';
            app.PolinomiocubicoconvelocitcalcolateTab = uitab(app.TabGroup);
            app.PolinomiocubicoconvelocitcalcolateTab.Title = 'Polinomio cubico con velocità calcolate';
            app.ProfilitrapezoidaliconanticipoTab = uitab(app.TabGroup);
            app.ProfilitrapezoidaliconanticipoTab.Title = 'Profili trapezoidali con anticipo';
            app.SimulazionePanel = uipanel(app.UIFigure);
            app.SimulazionePanel.Title = 'Simulazione';
            app.UIAxes = uiaxes(app.SimulazionePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.ZLim = [0 4];
            app.GiuntiPanel = uipanel(app.UIFigure);
            app.GiuntiPanel.Title = 'Giunti';
            app.GiuntiListBox = uilistbox(app.GiuntiPanel);
            app.GiuntiListBox.Items = {};
            app.GiuntiListBox.Value = {};
            app.EliminaButton = uibutton(app.GiuntiPanel, 'push');
            app.EliminaButton.ButtonPushedFcn = createCallbackFcn(app, @EliminaButtonPushed, true);
            app.EliminaButton.Tooltip = {'Elimina il giunto selezionato'};
            app.EliminaButton.Text = 'Elimina';
            app.TipologiaDropDownLabel = uilabel(app.GiuntiPanel);
            app.TipologiaDropDownLabel.Tooltip = {'Definisce la tipologia del giunto'};
            app.TipologiaDropDownLabel.Text = 'Tipologia:';
            app.TipologiaDropDown = uidropdown(app.GiuntiPanel);
            app.TipologiaDropDown.Items = {'Rotoidale', 'Prismatico'};
            app.TipologiaDropDown.Tooltip = {'Definisce la tipologia del giunto'};
            app.TipologiaDropDown.Value = 'Rotoidale';
            app.AggiungiButton = uibutton(app.GiuntiPanel, 'push');
            app.AggiungiButton.ButtonPushedFcn = createCallbackFcn(app, @AggiungiButtonPushed, true);
            app.AggiungiButton.Tooltip = {'Aggiunge il giunto in base ai dati inseriti'};
            app.AggiungiButton.Text = 'Aggiungi';
            app.RiferimentoDropDownLabel = uilabel(app.GiuntiPanel);
            app.RiferimentoDropDownLabel.Tooltip = {'Asse di riferimento su quale avviene il movimento'};
            app.RiferimentoDropDownLabel.Text = 'Riferimento:';
            app.RiferimentoDropDown = uidropdown(app.GiuntiPanel);
            app.RiferimentoDropDown.Items = {'x', 'y', 'z'};
            app.RiferimentoDropDown.Tooltip = {'Asse di riferimento su quale avviene il movimento'};
            app.RiferimentoDropDown.Value = 'x';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end